classdef IP_Gui_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ImageAxes              matlab.ui.control.UIAxes
        PredefinedImagesLabel  matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        LoadButton             matlab.ui.control.Button
        RunFiltersButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        im % Description
    end
    
    methods (Access = public)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second app.image in the file
            if strcmp(app.imagefile,'corn.tif')
                app.app.im = app.imread('corn.tif', 2);
            else
                try
                    app.app.im = app.imread(app.imagefile);
                catch ME
                    % If problem reading app.image, display error message
                    uialert(app.UIFigure, ME.message, 'app.image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(app.im,3)
                case 1
                    % Display the grayscale app.image
                    app.imagesc(app.app.imageAxes,app.im);
                    
                    % Plot all histograms with the same data for grayscale
                   
                    
                case 3
                    % Display the truecolor app.image
                    app.imagesc(app.app.imageAxes,app.im);
                    
                    % Plot the histograms
                    
                    
                otherwise
                    % Error when app.image is not grayscale or truecolor
                    uialert(app.UIFigure, 'app.image must be grayscale or truecolor.', 'app.image Error');
                    return;
            end
                
            function updateFilters(app,app.im)


            app.img_gray = rgb2gray(app.im);


            app.img_gauss = app.imgaussfilt(app.img_gray, 8);


            can = edge(app.img_gauss, "Canny", .1);
            app.imwrite(app.img_gauss,'Gauss.jpg');
            
            updateapp.image(app,Gauss.jpg);
            end


        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure app.image axes
            app.app.imageAxes.Visible = 'off';
            app.app.imageAxes.Colormap = gray(256);
            axis(app.app.imageAxes, 'app.image');
            
            % Update the app.image and histograms
            updateapp.image(app, 'peppers.png');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the app.image and histograms
            updateapp.image(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All app.image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateapp.image(app, fname);
            end
        end

        % Button pushed function: RunFiltersButton
        function RunFiltersButtonPushed(app, event)
          
       
        updateFilters(app,app.im);
        app.LoadButton.app.im

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Peppers', 'Corn'};
            app.DropDown.ItemsData = {'peppers.png', 'corn.tif'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'peppers.png';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.RunFiltersButton = uibutton(app.UIFigure, 'push');
            app.RunFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @RunFiltersButtonPushed, true);
            app.RunFiltersButton.Text = 'Run Filters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IP_Gui_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end